classdef nd2xbGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nd2xb                           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        loadUButton                     matlab.ui.control.Button
        uFileLabel                      matlab.ui.control.Label
        etaFileLabel                    matlab.ui.control.Label
        loadEtaButton                   matlab.ui.control.Button
        gaugeFileLabel                  matlab.ui.control.Label
        loadGaugeButton                 matlab.ui.control.Button
        XBeachboundarytypeLabel         matlab.ui.control.Label
        xbeachBoundaryTypeDropDown      matlab.ui.control.DropDown
        GaugenumberforscalarboundarytypeEditFieldLabel  matlab.ui.control.Label
        gaugeNumberScalar               matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
        loadUMessage                    matlab.ui.control.EditField
        loadEtaMessage                  matlab.ui.control.EditField
        loadGaugeMessage                matlab.ui.control.EditField
        pathUString                     matlab.ui.control.EditField
        pathEtaString                   matlab.ui.control.EditField
        pathGaugeString                 matlab.ui.control.EditField
        gaugeNumberCheck                matlab.ui.control.EditField
        xbeachManualButton              matlab.ui.control.Button
        GaugenameprefixEditFieldLabel   matlab.ui.control.Label
        gaugeNamePrefixEditField        matlab.ui.control.EditField
        loadGridButton                  matlab.ui.control.Button
        gridFileLabel                   matlab.ui.control.Label
        NameofdepfileLabel              matlab.ui.control.Label
        depNameEditField                matlab.ui.control.EditField
        NameofgrdfileinxdirectionLabel  matlab.ui.control.Label
        xgrdNameEditField               matlab.ui.control.EditField
        NameofgrdfileinydirectionLabel  matlab.ui.control.Label
        ygrdNameEditField               matlab.ui.control.EditField
        plotButton                      matlab.ui.control.Button
        checkPlotLabel                  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        loadGridMessage                 matlab.ui.control.EditField
        pathGridString                  matlab.ui.control.EditField
        editParamsTXTButton             matlab.ui.control.Button
        nonhManualButton                matlab.ui.control.Button
        outputGaugesEditButton          matlab.ui.control.Button
        nonhManualButton_2              matlab.ui.control.Button
    end

    
    properties (Access = private)
        nameGrid % name of grid file 
        nameU % name of U file
        nameEta % name of eta file
        nameGauge % name of gauge file
        pathGrid
        pathU
        pathEta
        pathGauge
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadUButton
        function loadUButtonPushed(app, event)
            [uName,uPath] = uigetfile("*.dat",'Select U results (.dat)');
            app.nameU = uName;
            app.pathU = uPath;
            drawnow;
            figure(app.nd2xb)
            app.loadUMessage.Value = uName+" is loaded succesfully!";
            app.pathUString.Value = path;
        end

        % Button pushed function: loadEtaButton
        function loadEtaButtonPushed(app, event)
            [etaName,etaPath] = uigetfile("*.dat", 'Select Eta results (.dat)');
            app.nameEta = etaName;
            app.pathEta = etaPath;
            drawnow;
            figure(app.nd2xb)
            app.loadEtaMessage.Value = etaName+" is loaded succesfully!";
            app.pathEtaString.Value = path;
        end

        % Button pushed function: loadGaugeButton
        function loadGaugeButtonPushed(app, event)
            [gaugeName,gaugePath] = uigetfile("*.dat",'Select gauge file (.dat)');
            app.nameGauge = gaugeName;
            app.pathGauge = gaugePath;
            drawnow;
            figure(app.nd2xb)
            app.loadGaugeMessage.Value = gaugeName+" is loaded succesfully!";
            app.pathGaugeString.Value = path;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if (string(app.loadGridMessage.Value) == "" || string(app.loadUMessage.Value) == "" || string(app.loadEtaMessage.Value) == "" || string(app.loadGaugeMessage.Value) == "")
                app.gaugeNumberCheck.Value = "Please load all the files!";
                app.gaugeNumberCheck.FontColor = "red";
            else
                app.gaugeNumberCheck.BackgroundColor = [0.65,0.65,0.65];
                app.gaugeNumberCheck.Value = "Running...";
                app.gaugeNumberCheck.FontColor = "green";
                fileName = string(app.pathGrid)+string(app.nameGrid);
                uFile = string(app.pathU)+string(app.nameU);
                etaFile = string(app.pathEta)+string(app.nameEta);
                gaugeFile = string(app.pathGauge)+string(app.nameGauge);
                prefix = app.gaugeNamePrefixEditField.Value;
                vectorOrScalar = app.xbeachBoundaryTypeDropDown.Value;
                if vectorOrScalar == "Vector"
                    app.gaugeNumberScalar.Value = 1;
                    gaugeNoForScalar = 1;
                else
                    gaugeNoForScalar = app.gaugeNumberScalar.Value;
                    
                end
               outputFileNames = [string(app.depNameEditField.Value); string(app.xgrdNameEditField.Value); string(app.ygrdNameEditField.Value)];
               pause(0.01)
               [gaugeMessage] = nd2xbCode(uFile,etaFile,gaugeFile,prefix,vectorOrScalar,gaugeNoForScalar,fileName,outputFileNames);
               app.gaugeNumberCheck.Value = gaugeMessage;
               app.gaugeNumberCheck.Value = "Success!";
               app.editParamsTXTButton.Enable = true;     
               app.outputGaugesEditButton.Enable = true;
            end
            

        end

        % Button pushed function: xbeachManualButton
        function xbeachManualButtonPushed(app, event)
            web("https://xbeach.readthedocs.io/en/latest/");
        end

        % Button pushed function: loadGridButton
        function loadGridButtonPushed(app, event)
            [gridName,gridPath] = uigetfile("*.asc");
            app.nameGrid = gridName;
            app.pathGrid = gridPath;
            drawnow;
            figure(app.nd2xb)
            app.loadGridMessage.Value = gridName+" is loaded succesfully!";
            app.pathGridString.Value = path;
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            [gridName,gridPath] = uigetfile("*.asc",'Select grid file (.asc)');
            [gaugeFile,gaugePath] = uigetfile("*.dat",'Select gauge file (.dat)');
            outputFileNames = ["bed"; "x"; "y"];
            drawnow;
            figure(app.nd2xb)
            gaugeCheckPlot(string(gridPath)+string(gridName),outputFileNames,string(gaugePath)+string(gaugeFile));
        end

        % Button pushed function: nonhManualButton
        function nonhManualButtonPushed(app, event)
            winopen('non-hydrostatic_report.pdf');
        end

        % Button pushed function: editParamsTXTButton
        function editParamsTXTButtonPushed(app, event)
            winopen('XBeachFiles\params.txt');
        end

        % Value changed function: xbeachBoundaryTypeDropDown
        function xbeachBoundaryTypeDropDownValueChanged(app, event)
            value = app.xbeachBoundaryTypeDropDown.Value;
            if value == "Scalar"
                app.gaugeNumberScalar.Editable = true;
            else
                app.gaugeNumberScalar.Editable = false;
            end
        end

        % Button pushed function: outputGaugesEditButton
        function outputGaugesEditButtonPushed(app, event)
            outputEditor
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nd2xb = uifigure('Visible', 'off');
            app.nd2xb.Name = 'ND2XB 1.1, Yagiz Arda Cicek (2021)';
            app.nd2xb.Icon = 'logo.png';
            app.GridLayout = uigridlayout(app.nd2xb);
            app.loadUButton = uibutton(app.GridLayout, 'push');
            app.loadUButton.ButtonPushedFcn = createCallbackFcn(app, @loadUButtonPushed, true);
            app.loadUButton.Tooltip = {'U velocity file obtained from NAMI DANCE simulation'; ''};
            app.loadUButton.Text = 'Load';
            app.uFileLabel = uilabel(app.GridLayout);
            app.uFileLabel.Tooltip = {'U velocity file obtained from NAMI DANCE simulation'; ''};
            app.uFileLabel.Text = 'U velocity file (.dat):';
            app.etaFileLabel = uilabel(app.GridLayout);
            app.etaFileLabel.Tooltip = {'Eta surface elevation file obtained from NAMI DANCE simulation'};
            app.etaFileLabel.Text = 'Eta surface elevation file (.dat):';
            app.loadEtaButton = uibutton(app.GridLayout, 'push');
            app.loadEtaButton.ButtonPushedFcn = createCallbackFcn(app, @loadEtaButtonPushed, true);
            app.loadEtaButton.Tooltip = {'Eta surface elevation file obtained from NAMI DANCE simulation'};
            app.loadEtaButton.Text = 'Load';
            app.gaugeFileLabel = uilabel(app.GridLayout);
            app.gaugeFileLabel.Tooltip = {'Gauge file in NAMI DANCE Gauge format'};
            app.gaugeFileLabel.Text = 'Gauge file (.dat):';
            app.loadGaugeButton = uibutton(app.GridLayout, 'push');
            app.loadGaugeButton.ButtonPushedFcn = createCallbackFcn(app, @loadGaugeButtonPushed, true);
            app.loadGaugeButton.Tooltip = {'Gauge file in NAMI DANCE Gauge format'};
            app.loadGaugeButton.Text = 'Load';
            app.XBeachboundarytypeLabel = uilabel(app.GridLayout);
            app.XBeachboundarytypeLabel.Tooltip = {'Option to use varying eta and u in XBeach boundary (vector) or constant value along the boundary (scalar)'};
            app.XBeachboundarytypeLabel.Text = 'XBeach boundary type:';
            app.xbeachBoundaryTypeDropDown = uidropdown(app.GridLayout);
            app.xbeachBoundaryTypeDropDown.Items = {'Vector', 'Scalar'};
            app.xbeachBoundaryTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @xbeachBoundaryTypeDropDownValueChanged, true);
            app.xbeachBoundaryTypeDropDown.Tooltip = {'Option to use varying eta and u in XBeach boundary (vector) or constant value along the boundary (scalar)'};
            app.xbeachBoundaryTypeDropDown.Value = 'Vector';
            app.GaugenumberforscalarboundarytypeEditFieldLabel = uilabel(app.GridLayout);
            app.GaugenumberforscalarboundarytypeEditFieldLabel.Text = 'Gauge number for scalar boundary type:';
            app.gaugeNumberScalar = uieditfield(app.GridLayout, 'numeric');
            app.gaugeNumberScalar.Limits = [1 Inf];
            app.gaugeNumberScalar.RoundFractionalValues = 'on';
            app.gaugeNumberScalar.Editable = 'off';
            app.gaugeNumberScalar.Tooltip = {'Specify gauge number whose water level and horizontal velocity results will be used to create scalar boundary condition for XBeach. Activate the box by selecting Scalar boundary type.'};
            app.gaugeNumberScalar.Value = 1;
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
            app.loadUMessage = uieditfield(app.GridLayout, 'text');
            app.loadUMessage.Editable = 'off';
            app.loadEtaMessage = uieditfield(app.GridLayout, 'text');
            app.loadEtaMessage.Editable = 'off';
            app.loadGaugeMessage = uieditfield(app.GridLayout, 'text');
            app.loadGaugeMessage.Editable = 'off';
            app.pathUString = uieditfield(app.GridLayout, 'text');
            app.pathUString.Editable = 'off';
            app.pathEtaString = uieditfield(app.GridLayout, 'text');
            app.pathEtaString.Editable = 'off';
            app.pathGaugeString = uieditfield(app.GridLayout, 'text');
            app.pathGaugeString.Editable = 'off';
            app.gaugeNumberCheck = uieditfield(app.GridLayout, 'text');
            app.xbeachManualButton = uibutton(app.GridLayout, 'push');
            app.xbeachManualButton.ButtonPushedFcn = createCallbackFcn(app, @xbeachManualButtonPushed, true);
            app.xbeachManualButton.Text = 'Click for XBeach Manual';
            app.GaugenameprefixEditFieldLabel = uilabel(app.GridLayout);
            app.GaugenameprefixEditFieldLabel.Tooltip = {'Prefix to identify gauges to be used for XBeach boundary'};
            app.GaugenameprefixEditFieldLabel.Text = 'Gauge name prefix:';
            app.gaugeNamePrefixEditField = uieditfield(app.GridLayout, 'text');
            app.gaugeNamePrefixEditField.Tooltip = {'Prefix to identify gauges to be used for XBeach boundary'};
            app.gaugeNamePrefixEditField.Value = 'XB';
            app.loadGridButton = uibutton(app.GridLayout, 'push');
            app.loadGridButton.ButtonPushedFcn = createCallbackFcn(app, @loadGridButtonPushed, true);
            app.loadGridButton.Tooltip = {'Grid file obtained from Surfer in .asc format'; ''};
            app.loadGridButton.Text = 'Load';
            app.gridFileLabel = uilabel(app.GridLayout);
            app.gridFileLabel.Tooltip = {'Grid file obtained from Surfer in .asc format'; ''};
            app.gridFileLabel.Text = 'Grid file (.asc):';
            app.NameofdepfileLabel = uilabel(app.GridLayout);
            app.NameofdepfileLabel.Tooltip = {'XBeach .dep file name'};
            app.NameofdepfileLabel.Text = 'Name of .dep file';
            app.depNameEditField = uieditfield(app.GridLayout, 'text');
            app.depNameEditField.Editable = 'off';
            app.depNameEditField.Tooltip = {'XBeach .dep file name'};
            app.depNameEditField.Value = 'bed';
            app.NameofgrdfileinxdirectionLabel = uilabel(app.GridLayout);
            app.NameofgrdfileinxdirectionLabel.Tooltip = {'XBeach .grd file name'};
            app.NameofgrdfileinxdirectionLabel.Text = 'Name of .grd file (in x direction):';
            app.xgrdNameEditField = uieditfield(app.GridLayout, 'text');
            app.xgrdNameEditField.Editable = 'off';
            app.xgrdNameEditField.Tooltip = {'XBeach .grd file name'};
            app.xgrdNameEditField.Value = 'x';
            app.NameofgrdfileinydirectionLabel = uilabel(app.GridLayout);
            app.NameofgrdfileinydirectionLabel.Tooltip = {'XBeach .grd file name'};
            app.NameofgrdfileinydirectionLabel.Text = 'Name of .grd file (in y direction):';
            app.ygrdNameEditField = uieditfield(app.GridLayout, 'text');
            app.ygrdNameEditField.Editable = 'off';
            app.ygrdNameEditField.Tooltip = {'XBeach .grd file name'};
            app.ygrdNameEditField.Value = 'y';
            app.plotButton = uibutton(app.GridLayout, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Tooltip = {'NAMI DANCE sample gauge coordinates should contain XBeach boundary for healthy interpolation. Please check if XBeach boundary is in the limits of sample gauges by checking and judging the plot manually. Do not go further if you did not control the plot.'};
            app.plotButton.Text = 'Plot';
            app.checkPlotLabel = uilabel(app.GridLayout);
            app.checkPlotLabel.Tooltip = {'NAMI DANCE sample gauge coordinates should contain XBeach boundary for healthy interpolation. Please check if XBeach boundary is in the limits of sample gauges by checking and judging the plot manually. Do not go further if you did not control the plot.'};
            app.checkPlotLabel.Text = 'Check NAMI DANCE sample gauge and XBeach boundary location:';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'ND2XB 1.0 (NAMI DANCE - XBeach Coupling Software)'; '18.10.2021'; 'This  software creates bed.dep, x.grd, y.grd, Boun_U.bcf file for XBeach Non-hydrostatic module and writes a params.txt file with updated xfile, yfile, nx, ny and depfile keywords. Files are overwritten; hence, a backup folder is created (latestBackup) for the abovementioned files.'; 'Credits goes here.'; ''};
            app.loadGridMessage = uieditfield(app.GridLayout, 'text');
            app.loadGridMessage.Editable = 'off';
            app.pathGridString = uieditfield(app.GridLayout, 'text');
            app.pathGridString.Editable = 'off';
            app.editParamsTXTButton = uibutton(app.GridLayout, 'push');
            app.editParamsTXTButton.ButtonPushedFcn = createCallbackFcn(app, @editParamsTXTButtonPushed, true);
            app.editParamsTXTButton.Enable = 'off';
            app.editParamsTXTButton.Text = 'Edit params.txt';
            app.nonhManualButton = uibutton(app.GridLayout, 'push');
            app.nonhManualButton.ButtonPushedFcn = createCallbackFcn(app, @nonhManualButtonPushed, true);
            app.nonhManualButton.Text = 'Click for Non-hydrostatic Manual';
            app.outputGaugesEditButton = uibutton(app.GridLayout, 'push');
            app.outputGaugesEditButton.ButtonPushedFcn = createCallbackFcn(app, @outputGaugesEditButtonPushed, true);
            app.outputGaugesEditButton.Enable = 'off';
            app.outputGaugesEditButton.Text = 'Edit Output Gauges (XBeach)';
            app.nonhManualButton_2 = uibutton(app.GridLayout, 'push');
            app.nonhManualButton_2.Enable = 'off';
            app.nonhManualButton_2.Text = 'Run XBeach Simulation! (not operating yet)';
            % Show the figure after all components are created
            app.nd2xb.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nd2xbGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.nd2xb)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nd2xb)
        end
    end
end