classdef outputEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        XBeachOutputEditor10YagizArdaCicek2021UIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        IntervaltimeofglobaloutputsEditFieldLabel  matlab.ui.control.Label
        globalOutputIntervalTimeEditField  matlab.ui.control.NumericEditField
        StarttimeofoutputinmorphologicaltimesLabel  matlab.ui.control.Label
        outputStartTimeEditField      matlab.ui.control.NumericEditField
        gaugeOutputCheckBox           matlab.ui.control.CheckBox
        gaugeOutputLabel              matlab.ui.control.Label
        globalOutputVariableButton    matlab.ui.control.Button
        IntervaltimeofpointandrunupgaugeoutputsLabel  matlab.ui.control.Label
        gaugeOutputIntervalTimeEditField  matlab.ui.control.NumericEditField
        gaugeOutputVariableButton     matlab.ui.control.Button
        gaugeOutputCoordinatesButton  matlab.ui.control.Button
        globalOutputOptionsLabel      matlab.ui.control.Label
        gaugeOutputOptionsLabel       matlab.ui.control.Label
        updateParamsTXTButton         matlab.ui.control.Button
        messageEditField              matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: gaugeOutputCheckBox
        function gaugeOutputCheckBoxValueChanged(app, event)
            value = app.gaugeOutputCheckBox.Value;
            if value == 1
                app.gaugeOutputIntervalTimeEditField.Editable = true;
                app.gaugeOutputCoordinatesButton.Enable = true;
                app.gaugeOutputVariableButton.Enable = true;
            else
                app.gaugeOutputIntervalTimeEditField.Editable = false;
                app.gaugeOutputCoordinatesButton.Enable = false;
                app.gaugeOutputVariableButton.Enable = false;
            end
        end

        % Button pushed function: globalOutputVariableButton
        function globalOutputVariableButtonPushed(app, event)
            fid = fopen("XBeachFiles\params.txt");
            paramsTXT = textscan(fid, '%s', 'CollectOutput',true, 'Delimiter', '\n');
            fclose(fid);
            paramsTXT = paramsTXT{1,1};
            paramsTXT{4,1} = "%%% Editted parameters: tintp,tintg,tstart,npoints,npointvar,nglobalvar      %%%";
            date = string(datetime('now'));
            
            paramsTXT{6,1} = "%%% date: "+date+"                                               %%%";
            paramsTXT{7,1} = "%%% Function: params.m and outputEditorGUI                                   %%%";
            paramsTXT = string(paramsTXT);
            
            indexNo = find(contains(paramsTXT,"tstart"));
            indexNo = indexNo(2,1);
            paramsTXT(indexNo, 1) = "tstart = " + string(app.outputStartTimeEditField.Value);

            indexNo = find(contains(paramsTXT,"tintg"));
            indexNo = indexNo(2,1);
            paramsTXT(indexNo, 1) = "tintg = " + string(app.globalOutputIntervalTimeEditField.Value);
            writematrix(paramsTXT, "XBeachFiles\params.txt",'QuoteStrings',0);
            globalOutputs
        end

        % Button pushed function: gaugeOutputCoordinatesButton
        function gaugeOutputCoordinatesButtonPushed(app, event)
            fid = fopen("XBeachFiles\params.txt");
            paramsTXT = textscan(fid, '%s', 'CollectOutput',true, 'Delimiter', '\n');
            fclose(fid);
            paramsTXT = paramsTXT{1,1};
            paramsTXT{4,1} = "%%% Editted parameters: tintp,tintg,tstart,npoints,npointvar,nglobalvar      %%%";
            date = string(datetime('now'));
            
            paramsTXT{6,1} = "%%% date: "+date+"                                               %%%";
            paramsTXT{7,1} = "%%% Function: params.m and outputEditorGUI                                   %%%";
            paramsTXT = string(paramsTXT);
            
            indexNo = find(contains(paramsTXT,"tstart"));
            indexNo = indexNo(2,1);
            paramsTXT(indexNo, 1) = "tstart = " + string(app.outputStartTimeEditField.Value);

            indexNo = find(contains(paramsTXT,"tintg"));
            indexNo = indexNo(2,1);
            paramsTXT(indexNo, 1) = "tintg = " + string(app.globalOutputIntervalTimeEditField.Value);
            
            indexNo = find(contains(paramsTXT,"tintp"));
            indexNo = indexNo(2,1);
            paramsTXT(indexNo, 1) = "tintp = " + string(app.gaugeOutputIntervalTimeEditField.Value);
            
            writematrix(paramsTXT, "XBeachFiles\params.txt",'QuoteStrings',0);
            
            
            gaugeOutputPoints
        end

        % Button pushed function: gaugeOutputVariableButton
        function gaugeOutputVariableButtonPushed(app, event)
            fid = fopen("XBeachFiles\params.txt");
            paramsTXT = textscan(fid, '%s', 'CollectOutput',true, 'Delimiter', '\n');
            fclose(fid);
            paramsTXT = paramsTXT{1,1};
            paramsTXT{4,1} = "%%% Editted parameters: tintp,tintg,tstart,npoints,npointvar,nglobalvar      %%%";
            date = string(datetime('now'));
            
            paramsTXT{6,1} = "%%% date: "+date+"                                               %%%";
            paramsTXT{7,1} = "%%% Function: params.m and outputEditorGUI                                   %%%";
            paramsTXT = string(paramsTXT);
            
            indexNo = find(contains(paramsTXT,"tstart"));
            indexNo = indexNo(2,1);
            paramsTXT(indexNo, 1) = "tstart = " + string(app.outputStartTimeEditField.Value);

            indexNo = find(contains(paramsTXT,"tintg"));
            indexNo = indexNo(2,1);
            paramsTXT(indexNo, 1) = "tintg = " + string(app.globalOutputIntervalTimeEditField.Value);
            
            indexNo = find(contains(paramsTXT,"tintp"));
            indexNo = indexNo(2,1);
            paramsTXT(indexNo, 1) = "tintp = " + string(app.gaugeOutputIntervalTimeEditField.Value);
            
            writematrix(paramsTXT, "XBeachFiles\params.txt",'QuoteStrings',0);
            
            gaugeOutputs
        end

        % Button pushed function: updateParamsTXTButton
        function updateParamsTXTButtonPushed(app, event)
            app.messageEditField.Value = "Running";
            if app.gaugeOutputCheckBox.Value == 0
                fid = fopen("XBeachFiles\params.txt");
                paramsTXT = textscan(fid, '%s', 'CollectOutput',true, 'Delimiter', '\n');
                fclose(fid);
                paramsTXT = paramsTXT{1,1};
                paramsTXT{4,1} = "%%% Editted parameters: tintp,tintg,tstart,npoints,npointvar,nglobalvar      %%%";
                date = string(datetime('now'));
                
                paramsTXT{6,1} = "%%% date: "+date+"                                               %%%";
                paramsTXT{7,1} = "%%% Function: params.m and outputEditorGUI                                   %%%";
                paramsTXT = string(paramsTXT);
                
                indexNo = find(contains(paramsTXT,"tstart"));
                indexNo = indexNo(2,1);
                paramsTXT(indexNo, 1) = "tstart = " + string(app.outputStartTimeEditField.Value);
    
                indexNo = find(contains(paramsTXT,"tintg"));
                indexNo = indexNo(2,1);
                paramsTXT(indexNo, 1) = "tintg = " + string(app.globalOutputIntervalTimeEditField.Value);
                writematrix(paramsTXT, "XBeachFiles\params.txt",'QuoteStrings',0);
            else
                fid = fopen("XBeachFiles\params.txt");
                paramsTXT = textscan(fid, '%s', 'CollectOutput',true, 'Delimiter', '\n');
                fclose(fid);
                paramsTXT = paramsTXT{1,1};
                paramsTXT{4,1} = "%%% Editted parameters: tintp,tintg,tstart,npoints,npointvar,nglobalvar      %%%";
                date = string(datetime('now'));
                
                paramsTXT{6,1} = "%%% date: "+date+"                                               %%%";
                paramsTXT{7,1} = "%%% Function: params.m and outputEditorGUI                                   %%%";
                paramsTXT = string(paramsTXT);
                
                indexNo = find(contains(paramsTXT,"tstart"));
                indexNo = indexNo(2,1);
                paramsTXT(indexNo, 1) = "tstart = " + string(app.outputStartTimeEditField.Value);
    
                indexNo = find(contains(paramsTXT,"tintg"));
                indexNo = indexNo(2,1);
                paramsTXT(indexNo, 1) = "tintg = " + string(app.globalOutputIntervalTimeEditField.Value);
                
                indexNo = find(contains(paramsTXT,"tintp"));
                indexNo = indexNo(2,1);
                paramsTXT(indexNo, 1) = "tintp = " + string(app.gaugeOutputIntervalTimeEditField.Value);
                
                writematrix(paramsTXT, "XBeachFiles\params.txt",'QuoteStrings',0); 
            end
            pause(2);
            app.messageEditField.Value = "Success!";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.XBeachOutputEditor10YagizArdaCicek2021UIFigure = uifigure('Visible', 'off');
            app.XBeachOutputEditor10YagizArdaCicek2021UIFigure.Name = 'XBeach Output Editor 1.0, Yagiz Arda Cicek (2021)';
            app.XBeachOutputEditor10YagizArdaCicek2021UIFigure.Icon = 'logo.png';
            app.GridLayout = uigridlayout(app.XBeachOutputEditor10YagizArdaCicek2021UIFigure);
            app.IntervaltimeofglobaloutputsEditFieldLabel = uilabel(app.GridLayout);
            app.IntervaltimeofglobaloutputsEditFieldLabel.Text = 'Interval time of global output (s):';
            app.globalOutputIntervalTimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.globalOutputIntervalTimeEditField.Limits = [1e-06 Inf];
            app.globalOutputIntervalTimeEditField.Value = 1;
            app.StarttimeofoutputinmorphologicaltimesLabel = uilabel(app.GridLayout);
            app.StarttimeofoutputinmorphologicaltimesLabel.Text = 'Start time of output, in morphological time (s):';
            app.outputStartTimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.outputStartTimeEditField.Limits = [0 Inf];
            app.gaugeOutputCheckBox = uicheckbox(app.GridLayout);
            app.gaugeOutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @gaugeOutputCheckBoxValueChanged, true);
            app.gaugeOutputCheckBox.Text = '';
            app.gaugeOutputLabel = uilabel(app.GridLayout);
            app.gaugeOutputLabel.Text = 'Gauge output?';
            app.globalOutputVariableButton = uibutton(app.GridLayout, 'push');
            app.globalOutputVariableButton.ButtonPushedFcn = createCallbackFcn(app, @globalOutputVariableButtonPushed, true);
            app.globalOutputVariableButton.Text = 'Click to add global output variables';
            app.IntervaltimeofpointandrunupgaugeoutputsLabel = uilabel(app.GridLayout);
            app.IntervaltimeofpointandrunupgaugeoutputsLabel.Text = 'Interval time of point and runup gauge output (s):';
            app.gaugeOutputIntervalTimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.gaugeOutputIntervalTimeEditField.Editable = 'off';
            app.gaugeOutputIntervalTimeEditField.Value = 1;
            app.gaugeOutputVariableButton = uibutton(app.GridLayout, 'push');
            app.gaugeOutputVariableButton.ButtonPushedFcn = createCallbackFcn(app, @gaugeOutputVariableButtonPushed, true);
            app.gaugeOutputVariableButton.Enable = 'off';
            app.gaugeOutputVariableButton.Text = 'Click to add gauge output variables';
            app.gaugeOutputCoordinatesButton = uibutton(app.GridLayout, 'push');
            app.gaugeOutputCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @gaugeOutputCoordinatesButtonPushed, true);
            app.gaugeOutputCoordinatesButton.Enable = 'off';
            app.gaugeOutputCoordinatesButton.Text = 'Click to add gauge output points';
            app.globalOutputOptionsLabel = uilabel(app.GridLayout);
            app.globalOutputOptionsLabel.Text = 'Global Output Options';
            app.gaugeOutputOptionsLabel = uilabel(app.GridLayout);
            app.gaugeOutputOptionsLabel.Text = 'Gauge Output Options';
            app.updateParamsTXTButton = uibutton(app.GridLayout, 'push');
            app.updateParamsTXTButton.ButtonPushedFcn = createCallbackFcn(app, @updateParamsTXTButtonPushed, true);
            app.updateParamsTXTButton.Text = 'Update params.txt';
            app.messageEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.XBeachOutputEditor10YagizArdaCicek2021UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = outputEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.XBeachOutputEditor10YagizArdaCicek2021UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.XBeachOutputEditor10YagizArdaCicek2021UIFigure)
        end
    end
end