classdef gaugeOutputPoints < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GaugeOutputPointsv10YagizArdaCicek2021UIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        outputVariableListButton  matlab.ui.control.Button
        completeButton            matlab.ui.control.Button
        outputVariablesArea       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: outputVariableListButton
        function outputVariableListButtonPushed(app, event)
            web("https://xbeach.readthedocs.io/en/latest/output_variables.html");
        end

        % Button pushed function: completeButton
        function completeButtonPushed(app, event)
            fid = fopen("XBeachFiles\params.txt");
            paramsTXT = textscan(fid, '%s', 'CollectOutput',true, 'Delimiter', '\n');
            fclose(fid);
            paramsTXT = paramsTXT{1,1};
            paramsTXT{4,1} = "%%% Editted parameters: tintp,tintg,tstart,npoints,npointvar,nglobalvar      %%%";
            date = string(datetime('now'));
            
            paramsTXT{6,1} = "%%% date: "+date+"                                               %%%";
            paramsTXT{7,1} = "%%% Function: params.m and outputEditorGUI                                   %%%";
            paramsTXT = string(paramsTXT);
            indexNoStart = find(contains(paramsTXT,"%%% Point Coordinates %%%"));
            indexNoEnd = find(contains(paramsTXT,"%%% Point Variables %%%"));
            addedLines = string(app.outputVariablesArea.Value);
            addedLines(all(strcmp(addedLines,""),2),:) = [];
            paramsTXT = [paramsTXT(1:indexNoStart);" ";"npoints = " + string(size(addedLines,1)); addedLines;" "; paramsTXT(indexNoEnd:end,1)];
            writematrix(paramsTXT, "XBeachFiles\params.txt",'QuoteStrings',0);
            pause(1);
            app.completeButton.Text = "Running...";
            pause(1);
            app.completeButton.Text = "Completed!";
            pause(0.7);
            app.completeButton.Text = "Complete & Save!";


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GaugeOutputPointsv10YagizArdaCicek2021UIFigure = uifigure('Visible', 'off');
            app.GaugeOutputPointsv10YagizArdaCicek2021UIFigure.Name = 'Gauge Output Points v1.0, Yagiz Arda Cicek (2021)';
            app.GaugeOutputPointsv10YagizArdaCicek2021UIFigure.Icon = 'logo.png';
            app.GridLayout = uigridlayout(app.GaugeOutputPointsv10YagizArdaCicek2021UIFigure);
            app.outputVariableListButton = uibutton(app.GridLayout, 'push');
            app.outputVariableListButton.ButtonPushedFcn = createCallbackFcn(app, @outputVariableListButtonPushed, true);
            app.outputVariableListButton.Text = 'Click here to see the list of output variables';
            app.completeButton = uibutton(app.GridLayout, 'push');
            app.completeButton.ButtonPushedFcn = createCallbackFcn(app, @completeButtonPushed, true);
            app.completeButton.Text = 'Complete & Save!';
            app.outputVariablesArea = uitextarea(app.GridLayout);
            app.outputVariablesArea.Value = {'Add gauge coordinates here line by line, delimited by space (X - Y) (delete this line):'; ''};
            % Show the figure after all components are created
            app.GaugeOutputPointsv10YagizArdaCicek2021UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gaugeOutputPoints

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GaugeOutputPointsv10YagizArdaCicek2021UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GaugeOutputPointsv10YagizArdaCicek2021UIFigure)
        end
    end
end