classdef nonErodible < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nonErodibleGUI                matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        loadGridButton                matlab.ui.control.Button
        gridFileLabel                 matlab.ui.control.Label
        ClicktoupdateparamstxtButton  matlab.ui.control.Button
        loadGridMessage               matlab.ui.control.EditField
        nonErodibleCheckBox           matlab.ui.control.CheckBox
        EnablehardstructuresLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        nameGrid
        pathGrid % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadGridButton
        function loadGridButtonPushed(app, event)
            [gridName,gridPath] = uigetfile("*.asc",'Select grid file (.asc)');
            drawnow;
            app.nameGrid = gridName;
            app.pathGrid = gridPath;
            figure(app.nonErodibleGUI)
            app.loadGridMessage.Value = gridName+" is loaded succesfully!";
        end

        % Button pushed function: ClicktoupdateparamstxtButton
        function ClicktoupdateparamstxtButtonPushed(app, event)
            if app.nonErodibleCheckBox.Value == 1
                
                if string(app.loadGridMessage.Value) == "0 is loaded succesfully!"
                    app.ClicktoupdateparamstxtButton.Text = "Load a valid file!";
                    pause(2);
                    app.ClicktoupdateparamstxtButton.Text = "Click to update params.txt";
                    
                
                else
                    fid = fopen("XBeachFiles\params.txt");
                    paramsTXT = textscan(fid, '%s', 'CollectOutput',true, 'Delimiter', '\n');
                    fclose(fid);
                    paramsTXT = paramsTXT{1,1};
                    paramsTXT{4,1} = "%%% Editted parameters: struct and ne_layer                                  %%%";
                    date = string(datetime('now'));
                    
                    paramsTXT{6,1} = "%%% date: "+date+"                                               %%%";
                    paramsTXT{7,1} = "%%% Function: params.m & nonErodibleGUI                                      %%%";
                    paramsTXT = string(paramsTXT);
                    indexNoStart = find(contains(paramsTXT,"%%% Hard Structures %%%"));
                    indexNoEnd = find(contains(paramsTXT,"%%% Flow numerics parameters %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%"));
                    addedLines = ["struct = 1"; "ne_layer = sediThickness.dep"];
    %                 addedLines(all(strcmp(addedLines,""),2),:) = [];
                    paramsTXT = [paramsTXT(1:indexNoStart);" ";addedLines;paramsTXT(indexNoEnd:end,1)];
                    
                    writematrix(paramsTXT, "XBeachFiles\params.txt",'QuoteStrings',0)
                    
                    nonErodibleFileName = string(app.pathGrid) + string(app.nameGrid);
                    nonErodibleCode(nonErodibleFileName);
                    app.ClicktoupdateparamstxtButton.Text = "Running...";
                    pause(1);
                    app.ClicktoupdateparamstxtButton.Text = "Completed!";
                    pause(0.7);
                    app.ClicktoupdateparamstxtButton.Text = "Click to update params.txt";
                    
                end
            else
                fid = fopen("XBeachFiles\params.txt");
                paramsTXT = textscan(fid, '%s', 'CollectOutput',true, 'Delimiter', '\n');
                fclose(fid);
                paramsTXT = paramsTXT{1,1};
                paramsTXT{4,1} = "%%% Editted parameters: struct and ne_layer                                  %%%";
                date = string(datetime('now'));
                
                paramsTXT{6,1} = "%%% date: "+date+"                                               %%%";
                paramsTXT{7,1} = "%%% Function: params.m & nonErodibleGUI                                      %%%";
                paramsTXT = string(paramsTXT);
                indexNoStart = find(contains(paramsTXT,"%%% Hard Structures %%%"));
                indexNoEnd = find(contains(paramsTXT,"%%% Flow numerics parameters %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%"));
                addedLines = ["%%% struct = 1"; "%%% ne_layer = sediThickness.dep"];
%                 addedLines(all(strcmp(addedLines,""),2),:) = [];
                paramsTXT = [paramsTXT(1:indexNoStart-1,1);" ";paramsTXT(indexNoStart,1);" ";addedLines;" "; paramsTXT(indexNoEnd:end,1)];
                
                writematrix(paramsTXT, "XBeachFiles\params.txt",'QuoteStrings',0)
                app.ClicktoupdateparamstxtButton.Text = "Running...";
                pause(1);
                app.ClicktoupdateparamstxtButton.Text = "Completed!";
                pause(0.7);
                app.ClicktoupdateparamstxtButton.Text = "Click to update params.txt";
                delete("XBeachFiles\sediThickness.dep")
            end
                
        end

        % Value changed function: nonErodibleCheckBox
        function nonErodibleCheckBoxValueChanged(app, event)
            value = app.nonErodibleCheckBox.Value;
            if value == 1
                app.gridFileLabel.Enable = true;
                app.loadGridButton.Enable = true;
                app.loadGridMessage.Enable = true;
            else
                app.gridFileLabel.Enable = false;
                app.loadGridButton.Enable = false;
                app.loadGridMessage.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nonErodibleGUI = uifigure('Visible', 'off');
            app.nonErodibleGUI.Name = 'Non-Erodible v1.0, Yagiz Arda Cicek (2021)';
            app.nonErodibleGUI.Icon = 'logo.png';
            app.GridLayout = uigridlayout(app.nonErodibleGUI);
            app.loadGridButton = uibutton(app.GridLayout, 'push');
            app.loadGridButton.ButtonPushedFcn = createCallbackFcn(app, @loadGridButtonPushed, true);
            app.loadGridButton.Enable = 'off';
            app.loadGridButton.Tooltip = {'Grid file of sediment thickness obtained from Surfer in .asc format. If there is a hard structure, the value of the point is equal to 0. The resolution and grid coordinates should be same as with bed.dep.'};
            app.loadGridButton.Text = 'Load';
            app.gridFileLabel = uilabel(app.GridLayout);
            app.gridFileLabel.Enable = 'off';
            app.gridFileLabel.Tooltip = {'Grid file of sediment thickness obtained from Surfer in .asc format. If there is a hard structure, the value of the point is equal to 0. The resolution and grid coordinates should be same as with bed.dep.'; ''; ''};
            app.gridFileLabel.Text = 'Sediment thickness grid file (.asc):';
            app.ClicktoupdateparamstxtButton = uibutton(app.GridLayout, 'push');
            app.ClicktoupdateparamstxtButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoupdateparamstxtButtonPushed, true);
            app.ClicktoupdateparamstxtButton.Text = 'Click to update params.txt';
            app.loadGridMessage = uieditfield(app.GridLayout, 'text');
            app.loadGridMessage.Editable = 'off';
            app.loadGridMessage.Enable = 'off';
            app.nonErodibleCheckBox = uicheckbox(app.GridLayout);
            app.nonErodibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @nonErodibleCheckBoxValueChanged, true);
            app.nonErodibleCheckBox.Text = '';
            app.EnablehardstructuresLabel = uilabel(app.GridLayout);
            app.EnablehardstructuresLabel.Text = 'Enable hard structures?';
            % Show the figure after all components are created
            app.nonErodibleGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nonErodible

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.nonErodibleGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nonErodibleGUI)
        end
    end
end